classdef Inverse_Kinematics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DHTable                      matlab.ui.control.Table
        LinkDefinations              matlab.ui.container.Panel
        LinkNo                       matlab.ui.control.NumericEditField
        thetaInput                   matlab.ui.control.NumericEditField
        dInput                       matlab.ui.control.NumericEditField
        aInput                       matlab.ui.control.NumericEditField
        alphaInput                   matlab.ui.control.NumericEditField
        DHUpdate                     matlab.ui.control.Button
        LinkNoLabel                  matlab.ui.control.Label
        ThetaLabel                   matlab.ui.control.Label
        dLabel                       matlab.ui.control.Label
        aLabel                       matlab.ui.control.Label
        alphaLabel                   matlab.ui.control.Label
        NumberofLinksEditFieldLabel  matlab.ui.control.Label
        TotalLinks                   matlab.ui.control.NumericEditField
        LinkType                     matlab.ui.control.DropDown
        INVERSEKINEMATICS            matlab.ui.control.Label
        EndPoseValues                matlab.ui.container.Panel
        EulerAnglesDropDownLabel     matlab.ui.control.Label
        EulerAngle                   matlab.ui.control.DropDown
        Endframeangledefination      matlab.ui.control.Label
        PHIEditFieldLabel            matlab.ui.control.Label
        PHI                          matlab.ui.control.NumericEditField
        THETAEditFieldLabel          matlab.ui.control.Label
        THETA                        matlab.ui.control.NumericEditField
        PSIEditFieldLabel            matlab.ui.control.Label
        PSI                          matlab.ui.control.NumericEditField
        PositioninXEditFieldLabel    matlab.ui.control.Label
        PX                           matlab.ui.control.NumericEditField
        PositioninYEditFieldLabel    matlab.ui.control.Label
        PY                           matlab.ui.control.NumericEditField
        PositioninZEditFieldLabel    matlab.ui.control.Label
        PZ                           matlab.ui.control.NumericEditField
        RUNINVERSEButton             matlab.ui.control.Button
        Inverse                      matlab.ui.control.Table
        RESETButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        theta = [];
        a   =   [];
        d   =   [];
        alpha = [];
%         type  = [];
        INVS = [];
        q =[];
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run startup_rvc.m
        end

        % Callback function: DHTable, DHUpdate
        function DHUpdateButtonPushed(app, event)
          n = app.TotalLinks.Value;
            i = app.LinkNo.Value;
            app.theta(i) = app.thetaInput.Value;
            app.d(i) = app.dInput.Value;
            app.a(i) = app.aInput.Value;
            app.alpha(i) = app.alphaInput.Value;
%             app.type(i) = app.LinkType.Value;
            
            if i==n
                for i = 1:n
                    DH(i,:) = [(pi/180)*app.theta(i), app.d(i), app.a(i), (pi/180)*app.alpha(i)];
                end
                app.DHTable.Data = DH;
            end
        end

        % Button pushed function: RUNINVERSEButton
        function RUNINVERSEButtonPushed(app, event)
          n =  app.TotalLinks.Value;
          DH = app.DHTable.Data
            for k = 1:n
                 L{k} = Link('d',DH(k,2), 'a', DH(k,3), 'alpha', DH(k,4));
            end
            for b = 1:n
                X(b,:) = L{b};
               end
            R = SerialLink(X)
            
            Px = app.PX.Value;
            Py = app.PY.Value;
            Pz = app.PZ.Value;
            Phi   = app.PHI.Value;
            Theta = app.THETA.Value;
            Psi   = app.PSI.Value;
            
            Eul = app.EulerAngle.Value;
            
            if app.EulerAngle.Value == 'ZYZ'
                Rot = eul2r(Phi,Theta,Psi,'deg')  
            else
                Rot = rotz(Phi*pi/180)*roty(Theta*pi/180)*rotx(Psi*pi/180)
            end
            Trans = [ Rot;0 0 0];
            Trans = [Trans,[Px;Py;Pz;1]];
            
            app.INVS = R.ikunc(Trans) ;
            for l = 1:n
                app.q(l) = app.INVS(:,l);
            end
             app.Inverse.Data = (app.q)';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DHTable = uitable(app.UIFigure);
            app.DHTable.ColumnName = {'Theta'; 'd'; 'a'; 'alpha'};
            app.DHTable.RowName = {''};
            app.DHTable.DisplayDataChangedFcn = createCallbackFcn(app, @DHUpdateButtonPushed, true);
            app.LinkDefinations = uipanel(app.UIFigure);
            app.LinkDefinations.Title = 'Enter Link Definations one by one';
            app.LinkNo = uieditfield(app.LinkDefinations, 'numeric');
            app.thetaInput = uieditfield(app.LinkDefinations, 'numeric');
            app.dInput = uieditfield(app.LinkDefinations, 'numeric');
            app.aInput = uieditfield(app.LinkDefinations, 'numeric');
            app.alphaInput = uieditfield(app.LinkDefinations, 'numeric');
            app.DHUpdate = uibutton(app.LinkDefinations, 'push');
            app.DHUpdate.ButtonPushedFcn = createCallbackFcn(app, @DHUpdateButtonPushed, true);
            app.DHUpdate.Text = 'Update';
            app.LinkNoLabel = uilabel(app.LinkDefinations);
            app.LinkNoLabel.Text = 'Link No.';
            app.ThetaLabel = uilabel(app.LinkDefinations);
            app.ThetaLabel.Text = 'Theta';
            app.dLabel = uilabel(app.LinkDefinations);
            app.dLabel.Text = 'd';
            app.aLabel = uilabel(app.LinkDefinations);
            app.aLabel.Text = 'a';
            app.alphaLabel = uilabel(app.LinkDefinations);
            app.alphaLabel.Text = 'alpha';
            app.NumberofLinksEditFieldLabel = uilabel(app.LinkDefinations);
            app.NumberofLinksEditFieldLabel.Text = 'Number of Links';
            app.TotalLinks = uieditfield(app.LinkDefinations, 'numeric');
            app.LinkType = uidropdown(app.LinkDefinations);
            app.LinkType.Items = {'Revolute', 'Prismatic'};
            app.LinkType.Value = 'Revolute';
            app.INVERSEKINEMATICS = uilabel(app.UIFigure);
            app.INVERSEKINEMATICS.Text = 'INVERSE KINEMATICS';
            app.EndPoseValues = uipanel(app.UIFigure);
            app.EndPoseValues.Title = 'Values of Robot''s End Pose';
            app.EulerAnglesDropDownLabel = uilabel(app.EndPoseValues);
            app.EulerAnglesDropDownLabel.Text = 'Euler Angles';
            app.EulerAngle = uidropdown(app.EndPoseValues);
            app.EulerAngle.Items = {'ZYZ', 'ZYX'};
            app.EulerAngle.Value = 'ZYZ';
            app.Endframeangledefination = uilabel(app.EndPoseValues);
            app.Endframeangledefination.Text = 'End frame angle defination';
            app.PHIEditFieldLabel = uilabel(app.EndPoseValues);
            app.PHIEditFieldLabel.Text = 'PHI';
            app.PHI = uieditfield(app.EndPoseValues, 'numeric');
            app.THETAEditFieldLabel = uilabel(app.EndPoseValues);
            app.THETAEditFieldLabel.Text = 'THETA';
            app.THETA = uieditfield(app.EndPoseValues, 'numeric');
            app.PSIEditFieldLabel = uilabel(app.EndPoseValues);
            app.PSIEditFieldLabel.Text = 'PSI';
            app.PSI = uieditfield(app.EndPoseValues, 'numeric');
            app.PositioninXEditFieldLabel = uilabel(app.EndPoseValues);
            app.PositioninXEditFieldLabel.Text = 'Position in X';
            app.PX = uieditfield(app.EndPoseValues, 'numeric');
            app.PositioninYEditFieldLabel = uilabel(app.EndPoseValues);
            app.PositioninYEditFieldLabel.Text = 'Position in Y';
            app.PY = uieditfield(app.EndPoseValues, 'numeric');
            app.PositioninZEditFieldLabel = uilabel(app.EndPoseValues);
            app.PositioninZEditFieldLabel.Text = 'Position in Z';
            app.PZ = uieditfield(app.EndPoseValues, 'numeric');
            app.RUNINVERSEButton = uibutton(app.EndPoseValues, 'push');
            app.RUNINVERSEButton.ButtonPushedFcn = createCallbackFcn(app, @RUNINVERSEButtonPushed, true);
            app.RUNINVERSEButton.Text = 'RUN INVERSE';
            app.Inverse = uitable(app.UIFigure);
            app.Inverse.ColumnName = {'Value of Joint Variables in Order 1 to n'};
            app.Inverse.RowName = {};
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.Text = 'RESET';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Inverse_Kinematics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end